classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TabGroup                    matlab.ui.container.TabGroup
        JacoStatusTab               matlab.ui.container.Tab
        GREENrunningREDstoppedLampLabel  matlab.ui.control.Label
        GREENrunningREDstoppedLamp  matlab.ui.control.Lamp
        SortSpoonsSwitchLabel       matlab.ui.control.Label
        SortSpoonsSwitch            matlab.ui.control.Switch
        EMERGENCYSTOPButton         matlab.ui.control.Button
        MessagefromCutleryMagicianPanel  matlab.ui.container.Panel
        StatusBox                   matlab.ui.control.Label
        WarningsPanel               matlab.ui.container.Panel
        WarningsBox                 matlab.ui.control.Label
        SortForksSwitchLabel        matlab.ui.control.Label
        SortForksSwitch             matlab.ui.control.Switch
        SortKnivesSwitchLabel       matlab.ui.control.Label
        SortKnivesSwitch            matlab.ui.control.Switch
        ResumeButton                matlab.ui.control.StateButton
        ResetButton                 matlab.ui.control.StateButton
        RobotConfigurationTab       matlab.ui.container.Tab
        q1SliderLabel               matlab.ui.control.Label
        q1Slider                    matlab.ui.control.Slider
        q2SliderLabel               matlab.ui.control.Label
        q2Slider                    matlab.ui.control.Slider
        q3SliderLabel               matlab.ui.control.Label
        q3Slider                    matlab.ui.control.Slider
        q4SliderLabel               matlab.ui.control.Label
        q4Slider                    matlab.ui.control.Slider
        q5SliderLabel               matlab.ui.control.Label
        q5Slider                    matlab.ui.control.Slider
        q6SliderLabel               matlab.ui.control.Label
        q6Slider                    matlab.ui.control.Slider
        JointAnglesLabel            matlab.ui.control.Label
        xEditFieldLabel             matlab.ui.control.Label
        xEditField                  matlab.ui.control.NumericEditField
        yEditFieldLabel             matlab.ui.control.Label
        yEditField                  matlab.ui.control.NumericEditField
        zEditFieldLabel             matlab.ui.control.Label
        zEditField                  matlab.ui.control.NumericEditField
        CartesianMovementLabel      matlab.ui.control.Label
        OtherTab                    matlab.ui.container.Tab
        TestForcedCollisionButton   matlab.ui.control.StateButton
        TestSimulatedLightCurtainButton  matlab.ui.control.Button
    end


    properties (Access = private)
         robot; % Jaco robot links and properties
         
         qHomePose = [0 pi deg2rad(341) pi/2 2*pi 0];
         
         spoonLoc = transl(1.7, 1.5, 0.6);
        forkLoc = transl(1.8, 1.5, 0.6);
        knifeLoc = transl(1.9, 1.5, 0.6);
         containerOneLoc = transl(1.4, 1.5, 0.6);
        containerTwoLoc = transl(1.5, 1.5, 0.6);
        containerThreeLoc = transl(1.6, 1.5, 0.6);

         eStopLoc = transl(2.6, 1.2, 0.6);            %Location needs to be fixed up, this is a random number5
        barrier1Loc = transl(0.8, 1.5, 0.7);
        barrier2Loc = transl(2.5, 1.5, 0.7);
        barrier3Loc = transl(1.1, 1.9, 0.7);
        barrier4Loc = transl(2.1, 1.9, 0.7);
         
         forcedCollisionLoc = transl(0.6, 1, 0.1);
         handLoc = transl(1.5, 0.3, 0.4);
         
         spoonMesh_h;
         spoonVertexCount;
         spoonVerts;
         forkMesh_h;
         forkVertexCount;
         forkVerts;
         knifeMesh_h;
         knifeVertexCount;
         knifeVerts;
         eStopMesh_h;
         barrier1Mesh_h;
         barrier2Mesh_h;
         barrier3Mesh_h;
         barrier4Mesh_h;
         
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
        %When the GUI starts, create the robot and environment: Ready for simulation    
            uicontrol('Style', 'Text', 'String', 'Please wait');
            app.StatusBox.Text = "Please wait while I create my simulation :)" %Updates status box
            
            %This section loads the robot
            app.robot = Jaco();
            app.robot.GetJacoRobot();
            app.robot.PlotAndColourRobot();
            
            
            jacoBase = transl(2.1, 1.5, 0.7)*trotz(pi);
            app.robot.model.base = jacoBase;
            
            app.robot.model.plotopt = {'nojoints', 'noname', 'noshadow','nowrist'};
            app.robot.model.plot(app.qHomePose, 'scale', app.robot.scale, 'workspace', app.robot.workspace);      %Plot model         
            hold on;
            
            % This section will now load the environment
            xOffset = 0;
            yOffset = 0;
            zOffset = -0.5;
            [partMesh,f,v] = Environment('kitchen.ply', xOffset, yOffset, zOffset);
            benchMesh_h = partMesh;
            benchFaces = f;
            benchVertices = v;
            % benchFN = benchMesh_h.FaceNormals;
            hold on;
            
            %Floor
            x = [- 6 -6; 6 6];
            y = [-2 2; -2 2];
            z = [-0.5 -0.5; -0.5 -0.5];
            floor_h = background('floor.jpeg', x, y, z);
            
            %Window Wall
            x = [4 -4; 4 -4];
            y = [2.2 2.2; 2.2 2.2];
            z = [2 2; -0.5 -0.5]; 
            window_h = background('windowWall.jpg', x, y, z);
            
            %Warning Sign
            x = [1.1 1.1; 1.1 1.1];
            y = [1.4 1.6; 1.4 1.6];
            z = [1.4 1.4; 1.2 1.2]; 
            warningSign_h = background('WarningSign.jpg', x, y, z);
            
            %Import containers
            %Locations of each container (hard coded):
            
            
            %ContainerOneLoc = transl(1.41, 1, 0.25);
            
            [partMesh, f, v] = Environment('Container1.ply', app.containerOneLoc(1,4), app.containerOneLoc(2,4), app.containerOneLoc(3,4));
            containerOneMesh_h = partMesh;
            containerOneFaces = f;
            containerOneVertices = v;
            % containerOneFN = containerOneMesh_h.FaceNormals;
            hold on;
            
            [partMesh, f, v] = Environment('Container2.ply', app.containerTwoLoc(1,4), app.containerTwoLoc(2,4), app.containerTwoLoc(3,4));
            containerTwoMesh_h = partMesh;
            containerTwoFaces = f;
            containerTwoVertices = v;
            % containerTwoFaceNorms = containerTwoMesh_h.FaceNormals;
            hold on;
            
            partMesh = Environment('Container3.ply', app.containerThreeLoc(1,4), app.containerThreeLoc(2,4), app.containerThreeLoc(3,4));
            containerThreeMesh_h = partMesh;
            containerThreeFaces = f;
            containerThreeVertices = v;
            % containerThreeFaceNorms = containerThreeMesh_h.FaceNormals;
            hold on;
            
            %Safety Features - i.e eStop, encasing, warning signs,
                        
            %eStop
            partMesh = Environment('eStop.ply', app.eStopLoc(1,4), app.eStopLoc(2,4), app.eStopLoc(3,4)); %Rescale Estop lol
            app.eStopMesh_h = partMesh;
            hold on;
            
            %Barrier
            
            partMesh = Environment('SafetyBarrier.ply', app.barrier1Loc(1,4), app.barrier1Loc(2,4), app.barrier1Loc(3,4));
            app.barrier1Mesh_h = partMesh;
            hold on;
            
            partMesh = Environment('SafetyBarrier.ply', app.barrier2Loc(1,4), app.barrier2Loc(2,4), app.barrier2Loc(3,4));
            app.barrier2Mesh_h = partMesh;
            hold on;
            
            %Barriers against wall
            
            partMesh = Environment('SafetyBarrier2.ply', app.barrier3Loc(1,4), app.barrier3Loc(2,4), app.barrier3Loc(3,4));
            app.barrier3Mesh_h = partMesh;
            hold on;
            
            partMesh = Environment('SafetyBarrier2.ply', app.barrier4Loc(1,4), app.barrier4Loc(2,4), app.barrier4Loc(3,4));
            app.barrier4Mesh_h = partMesh;
            hold on;
            
            %light curtain
            for z = 0.7:0.05:1.1
            line('XData', [0.8 2.5], 'YData', [1.2 1.2], 'Zdata', [z z], 'Color', [1 0 0]);
            end
            
                       
            % Import Cutlery
            
            [spoonMesh, spoonVertexCount, spoonVerts] = PlotCutlery('Spoon.ply', app.spoonLoc(1,4), app.spoonLoc(2,4), app.spoonLoc(3,4));
            app.spoonMesh_h = spoonMesh;
            app.spoonVertexCount = spoonVertexCount;
            app.spoonVerts = spoonVerts;
            
            [forkMesh, forkVertexCount, forkVerts] = PlotCutlery('Fork.ply', app.forkLoc(1,4), app.forkLoc(2,4), app.forkLoc(3,4));
            app.forkMesh_h = forkMesh;
            app.forkVertexCount = forkVertexCount;
            app.forkVerts = forkVerts;
            
            [knifeMesh, knifeVertexCount, knifeVerts] = PlotCutlery('Knife.ply', app.knifeLoc(1,4), app.knifeLoc(2,4), app.knifeLoc(3,4));
            app.knifeMesh_h = knifeMesh;
            app.knifeVertexCount = knifeVertexCount;
            app.knifeVerts = knifeVerts;
                                             
            app.StatusBox.Text = "Hi! I am your Cutlery Magician. Press a switch to begin." %Updates status box
            uicontrol('Style', 'Text', 'String', 'Ready');
        end

        % Value changing function: q1Slider
        function q1SliderValueChanging(app, event)
            changingValue = event.Value;
            joint = 1;
            angle = changingValue;
            UpdateJointAngles(app.robot, joint, angle);
           
        end

        % Value changing function: q2Slider
        function q2SliderValueChanging(app, event)
            changingValue = event.Value;
            joint = 2;
            angle = changingValue;
            UpdateJointAngles(app.robot, joint, angle);
        end

        % Value changing function: q3Slider
        function q3SliderValueChanging(app, event)
            changingValue = event.Value;
            joint = 3;
            angle = changingValue;
            UpdateJointAngles(app.robot, joint, angle);
        end

        % Value changing function: q4Slider
        function q4SliderValueChanging(app, event)
            changingValue = event.Value;
            joint = 4;
            angle = changingValue;
            UpdateJointAngles(app.robot, joint, angle);
        end

        % Value changing function: q5Slider
        function q5SliderValueChanging(app, event)
            changingValue = event.Value;
            joint = 5;
            angle = changingValue;
            UpdateJointAngles(app.robot, joint, angle);
        end

        % Value changing function: q6Slider
        function q6SliderValueChanging(app, event)
            changingValue = event.Value;
            joint = 6;
            angle = changingValue;
            UpdateJointAngles(app.robot, joint, angle);
        end

        % Value changed function: xEditField
        function xEditFieldValueChanged(app, event)
            value = app.xEditField.Value;
            cartesian = 'x';
            variable = value;
            UpdateCartesian(app.robot, cartesian, variable);
        end

        % Value changed function: yEditField
        function yEditFieldValueChanged(app, event)
            value = app.yEditField.Value;
            cartesian = 'y';
            variable = value;
            UpdateCartesian(app.robot, cartesian, variable);
            app.StatusBox.Text = "Updated Joint Angles"
        end

        % Value changed function: zEditField
        function zEditFieldValueChanged(app, event)
            value = app.zEditField.Value;
            cartesian = 'z';
            variable = value;
            UpdateCartesian(app.robot, cartesian, variable);
        end

        % Button pushed function: EMERGENCYSTOPButton
        function EMERGENCYSTOPButtonPushed(app, event)
            app.WarningsBox.Text = "EMERGENCY STOP BUTTON PUSHED"
            eStopButton = 1;
            uiwait;
        end

        % Value changed function: SortSpoonsSwitch
        function SwitchSpoonValueChanged(app, event)
            value = app.SortSpoonsSwitch.Value;
%             switchON = value;
%             if switchON == value;
            
            qSpoon = app.robot.model.ikcon(app.spoonLoc);
            qContainerOne = app.robot.model.ikcon(app.containerOneLoc);
            qContainerTwo = app.robot.model.ikcon(app.containerTwoLoc);
            qContainerThree = app.robot.model.ikcon(app.containerThreeLoc);
            
            app.StatusBox.Text = "Sorting Spoons"
            jacoMove(app.qHomePose, qSpoon, app.robot);
            
            qMatrix = objectMove(qSpoon, qContainerThree, app.robot, app.spoonVerts, app.spoonVertexCount, app.spoonMesh_h);
            collisionStatus = CheckForCollision(app.robot, qMatrix, app.barrier1Mesh_h.Vertices, app.barrier1Mesh_h.Faces, app.barrier1Mesh_h.FaceNormals)
                if collisionStatus == 1
                  app.WarningsBox.Text = "Collision Detected!!! Robot has paused"
                  while collisionStatus == 1
                     app.WarningsBox.Text = "Collision Detected!!! Robot has paused - Press 'Reset' to move back to Home"
                     pause();
                  
                  
                  end
                  
                else
                    app.WarningsBox.Text = "No collisions: Cutlery Magician safely moving~"
                    jacoMove(qContainerThree, app.qHomePose, app.robot);
                    return
                end
                
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            
        end

        % Value changed function: ResumeButton
        function ResumeButtonValueChanged(app, event)
            value = app.ResumeButton.Value;
            uiresume;
            app.WarningsBox.Text = "Resumed"
        end

        % Value changed function: ResetButton
        function ResetButtonValueChanged(app, event)
            value = app.ResetButton.Value;
            app.WarningsBox.Text = "Beware of moving robot"
            app.StatusBox.Text = "- Cutlery Magician resetting -"
            qCollision = app.robot.model.getpos();
            jacoMove(qCollision, app.qHomePose, app.robot);
        end

        % Value changed function: TestForcedCollisionButton
        function TestForcedCollisionButtonValueChanged(app, event)
            value = app.TestForcedCollisionButton.Value;
                                       
            app.StatusBox.Text = "Forced Collision Simulation"
            
            
            qForcedCollision = app.robot.model.ikcon(app.forcedCollisionLoc);
            
            qMatrix = jacoMove(app.qHomePose, qForcedCollision, app.robot);
            collisionStatus = CheckForCollision(app.robot, qMatrix, app.barrier1Mesh_h.Vertices, app.barrier1Mesh_h.Faces, app.barrier1Mesh_h.FaceNormals)
                if collisionStatus == 1
                  app.WarningsBox.Text = "Collision Detected!!! Robot has paused"
                  while collisionStatus == 1
                     app.WarningsBox.Text = "Collision Detected!!! Robot has paused - Press 'Reset' to move back to Home"
                     pause();
                  
                  
                  end
                  
                else
                    app.WarningsBox.Text = "No collisions: Cutlery Magician safely moving~"
                    jacoMove(qForcedCollision, app.qHomePose, app.robot);
                    return
                end
        end

        % Value changed function: SortForksSwitch
        function SortForksSwitchValueChanged(app, event)
            value = app.SortForksSwitch.Value;
            
%             switchON = value;
%             if switchON == value;
            
            qFork = app.robot.model.ikcon(app.forkLoc);
            qContainerOne = app.robot.model.ikcon(app.containerOneLoc);
            qContainerTwo = app.robot.model.ikcon(app.containerTwoLoc);
            qContainerThree = app.robot.model.ikcon(app.containerThreeLoc);
            
            app.StatusBox.Text = "Sorting Forks"
            jacoMove(app.qHomePose, qFork, app.robot);
            
            qMatrix = objectMove(qFork, qContainerTwo, app.robot, app.forkVerts, app.forkVertexCount, app.forkMesh_h);
            collisionStatus = CheckForCollision(app.robot, qMatrix, app.barrier1Mesh_h.Vertices, app.barrier1Mesh_h.Faces, app.barrier1Mesh_h.FaceNormals)
                if collisionStatus == 1
                  app.WarningsBox.Text = "Collision Detected!!! Robot has paused"
                  while collisionStatus == 1
                     app.WarningsBox.Text = "Collision Detected!!! Robot has paused - Press 'Reset' to move back to Home"
                     pause();
                  
                  
                  end
                  
                else
                    app.WarningsBox.Text = "No collisions: Cutlery Magician safely moving~"
                    jacoMove(qContainerTwo, app.qHomePose, app.robot);
                    return
                end
        end

        % Value changed function: SortKnivesSwitch
        function SortKnivesSwitchValueChanged(app, event)
            value = app.SortKnivesSwitch.Value;
            qKnife = app.robot.model.ikcon(app.knifeLoc);
            qContainerOne = app.robot.model.ikcon(app.containerOneLoc);
                        
            app.StatusBox.Text = "Sorting Knives"
            jacoMove(app.qHomePose, qKnife, app.robot);
            
            qMatrix = objectMove(qKnife, qContainerOne, app.robot, app.knifeVerts, app.knifeVertexCount, app.knifeMesh_h);
            collisionStatus = CheckForCollision(app.robot, qMatrix, app.barrierMesh_h.Vertices, app.barrierMesh_h.Faces, app.barrierMesh_h.FaceNormals)
                if collisionStatus == 1
                  app.WarningsBox.Text = "Collision Detected!!! Robot has paused"
                  while collisionStatus == 1
                     app.WarningsBox.Text = "Collision Detected!!! Robot has paused - Press 'Reset'"
                     pause();
                  
                  
                  end
                  
                else
                    app.WarningsBox.Text = "No collisions: Cutlery Magician safely moving~"
                    jacoMove(qContainerOne, app.qHomePose, app.robot);
                    return
                end
        end

        % Button pushed function: TestSimulatedLightCurtainButton
        function TestSimulatedLightCurtainButtonPushed(app, event)


            [handMesh, VertexCount, Verts] = PlotCutlery('hand2.ply', app.handLoc(1,4), app.handLoc(2,4), app.handLoc(3,4));
            handMesh_h = handMesh;
            handVerts = Verts;
            handVertexCount = VertexCount;
            %
            handFaceNormals = zeros(size(handMesh_h.Faces,1),3);
            for faceIndex = 1:size(handMesh_h.Faces,1)
                v1 = handMesh_h.Vertices(handMesh_h.Faces(faceIndex,1)',:);
                v2 = handMesh_h.Vertices(handMesh_h.Faces(faceIndex,2)',:);
                v3 = handMesh_h.Vertices(handMesh_h.Faces(faceIndex,3)',:);
                handFaceNormals(faceIndex,:) = unit(cross(v2-v1,v3-v1));
            end
            
            startPoints = [];
            for x = 0.6:0.1:1.2
                for y = 0.7
                    for z = 0.2:0.05:0.35
                        startPoints = [startPoints; x y z];
                    end
                    
                end
                
            end
    
            finishPoints = [];
            for x = 1.2:0.1:1.8
                for y = 0.7
                    for z = 0.35:0.05:0.5
                        finishPoints = [finishPoints; x y z];
                    end
                    
                end
                
            end
            
            steps = 10;
            handPose = app.handLoc;
            
            forwardTR = makehgtform('translate',[0,0.05,0]);
            %forwardTR = transl(1.5, 0.7, 0);
            for i = 1:steps
                
                % Move forward 
                handPose = handPose*forwardTR;
                % Transform the vertices
                updatedPoints = [handPose * [handVerts,ones(handVertexCount,1)]']';
                lightCurtainStatus = LaserCollision(startPoints, finishPoints, handMesh_h.Vertices, handMesh_h.Faces, handFaceNormals); 
                if lightCurtainStatus == 1
                  app.WarningsBox.Text = "Collision Detected!!! Robot has paused";
                  while lightCurtainStatus == 1
                     app.WarningsBox.Text = "Collision Detected!!! Robot has paused - Press 'Reset'"
                     pause();
                  end     
                           
                else
                    app.WarningsBox.Text = "No collisions: Cutlery Magician safely moving~"
            %         return
                end
                % Update the mesh vertices in the patch handle
                handMesh_h.Vertices = updatedPoints(:,1:3);
                drawnow();   
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.JacoStatusTab = uitab(app.TabGroup);
            app.JacoStatusTab.Title = 'Jaco Status';
            app.GREENrunningREDstoppedLampLabel = uilabel(app.JacoStatusTab);
            app.GREENrunningREDstoppedLampLabel.Text = {'GREEN = running'; 'RED = stopped'};
            app.GREENrunningREDstoppedLamp = uilamp(app.JacoStatusTab);
            app.SortSpoonsSwitchLabel = uilabel(app.JacoStatusTab);
            app.SortSpoonsSwitchLabel.Text = 'Sort Spoons';
            app.SortSpoonsSwitch = uiswitch(app.JacoStatusTab, 'slider');
            app.SortSpoonsSwitch.ValueChangedFcn = createCallbackFcn(app, @SwitchSpoonValueChanged, true);
            app.EMERGENCYSTOPButton = uibutton(app.JacoStatusTab, 'push');
            app.EMERGENCYSTOPButton.ButtonPushedFcn = createCallbackFcn(app, @EMERGENCYSTOPButtonPushed, true);
            app.EMERGENCYSTOPButton.Text = {'EMERGENCY '; 'STOP'};
            app.MessagefromCutleryMagicianPanel = uipanel(app.JacoStatusTab);
            app.MessagefromCutleryMagicianPanel.Title = 'Message from Cutlery Magician:';
            app.StatusBox = uilabel(app.MessagefromCutleryMagicianPanel);
            app.StatusBox.Text = '';
            app.WarningsPanel = uipanel(app.JacoStatusTab);
            app.WarningsPanel.Title = 'Warnings:';
            app.WarningsBox = uilabel(app.WarningsPanel);
            app.WarningsBox.Text = '';
            app.SortForksSwitchLabel = uilabel(app.JacoStatusTab);
            app.SortForksSwitchLabel.Text = 'Sort Forks';
            app.SortForksSwitch = uiswitch(app.JacoStatusTab, 'slider');
            app.SortForksSwitch.ValueChangedFcn = createCallbackFcn(app, @SortForksSwitchValueChanged, true);
            app.SortKnivesSwitchLabel = uilabel(app.JacoStatusTab);
            app.SortKnivesSwitchLabel.Text = 'Sort Knives';
            app.SortKnivesSwitch = uiswitch(app.JacoStatusTab, 'slider');
            app.SortKnivesSwitch.ValueChangedFcn = createCallbackFcn(app, @SortKnivesSwitchValueChanged, true);
            app.ResumeButton = uibutton(app.JacoStatusTab, 'state');
            app.ResumeButton.ValueChangedFcn = createCallbackFcn(app, @ResumeButtonValueChanged, true);
            app.ResumeButton.Text = 'Resume';
            app.ResetButton = uibutton(app.JacoStatusTab, 'state');
            app.ResetButton.ValueChangedFcn = createCallbackFcn(app, @ResetButtonValueChanged, true);
            app.ResetButton.Text = 'Reset';
            app.RobotConfigurationTab = uitab(app.TabGroup);
            app.RobotConfigurationTab.Title = 'Robot Configuration';
            app.q1SliderLabel = uilabel(app.RobotConfigurationTab);
            app.q1SliderLabel.Text = 'q1';
            app.q1Slider = uislider(app.RobotConfigurationTab);
            app.q1Slider.Limits = [-360 360];
            app.q1Slider.ValueChangingFcn = createCallbackFcn(app, @q1SliderValueChanging, true);
            app.q1Slider.MinorTicks = [-360 -340 -320 -300 -280 -260 -240 -220 -200 -180 -160 -140 -120 -100 -80 -60 -40 -20 0 20 40 60 80 100 120 140 160 180 200 220 240 260 280 300 320 340 360];
            app.q2SliderLabel = uilabel(app.RobotConfigurationTab);
            app.q2SliderLabel.Text = 'q2';
            app.q2Slider = uislider(app.RobotConfigurationTab);
            app.q2Slider.Limits = [47 313];
            app.q2Slider.ValueChangingFcn = createCallbackFcn(app, @q2SliderValueChanging, true);
            app.q2Slider.Value = 47;
            app.q3SliderLabel = uilabel(app.RobotConfigurationTab);
            app.q3SliderLabel.Text = 'q3';
            app.q3Slider = uislider(app.RobotConfigurationTab);
            app.q3Slider.Limits = [19 341];
            app.q3Slider.ValueChangingFcn = createCallbackFcn(app, @q3SliderValueChanging, true);
            app.q3Slider.Value = 19;
            app.q4SliderLabel = uilabel(app.RobotConfigurationTab);
            app.q4SliderLabel.Text = 'q4';
            app.q4Slider = uislider(app.RobotConfigurationTab);
            app.q4Slider.Limits = [-180 180];
            app.q4Slider.ValueChangingFcn = createCallbackFcn(app, @q4SliderValueChanging, true);
            app.q5SliderLabel = uilabel(app.RobotConfigurationTab);
            app.q5SliderLabel.Text = 'q5';
            app.q5Slider = uislider(app.RobotConfigurationTab);
            app.q5Slider.Limits = [65 295];
            app.q5Slider.ValueChangingFcn = createCallbackFcn(app, @q5SliderValueChanging, true);
            app.q5Slider.Value = 65;
            app.q6SliderLabel = uilabel(app.RobotConfigurationTab);
            app.q6SliderLabel.Text = 'q6';
            app.q6Slider = uislider(app.RobotConfigurationTab);
            app.q6Slider.Limits = [-360 360];
            app.q6Slider.ValueChangingFcn = createCallbackFcn(app, @q6SliderValueChanging, true);
            app.JointAnglesLabel = uilabel(app.RobotConfigurationTab);
            app.JointAnglesLabel.Text = 'Joint Angles';
            app.xEditFieldLabel = uilabel(app.RobotConfigurationTab);
            app.xEditFieldLabel.Text = 'x';
            app.xEditField = uieditfield(app.RobotConfigurationTab, 'numeric');
            app.xEditField.ValueChangedFcn = createCallbackFcn(app, @xEditFieldValueChanged, true);
            app.yEditFieldLabel = uilabel(app.RobotConfigurationTab);
            app.yEditFieldLabel.Text = 'y';
            app.yEditField = uieditfield(app.RobotConfigurationTab, 'numeric');
            app.yEditField.ValueChangedFcn = createCallbackFcn(app, @yEditFieldValueChanged, true);
            app.zEditFieldLabel = uilabel(app.RobotConfigurationTab);
            app.zEditFieldLabel.Text = 'z';
            app.zEditField = uieditfield(app.RobotConfigurationTab, 'numeric');
            app.zEditField.ValueChangedFcn = createCallbackFcn(app, @zEditFieldValueChanged, true);
            app.CartesianMovementLabel = uilabel(app.RobotConfigurationTab);
            app.CartesianMovementLabel.Text = 'Cartesian Movement';
            app.OtherTab = uitab(app.TabGroup);
            app.OtherTab.Title = 'Other';
            app.TestForcedCollisionButton = uibutton(app.OtherTab, 'state');
            app.TestForcedCollisionButton.ValueChangedFcn = createCallbackFcn(app, @TestForcedCollisionButtonValueChanged, true);
            app.TestForcedCollisionButton.Text = 'Test Forced Collision';
            app.TestSimulatedLightCurtainButton = uibutton(app.OtherTab, 'push');
            app.TestSimulatedLightCurtainButton.ButtonPushedFcn = createCallbackFcn(app, @TestSimulatedLightCurtainButtonPushed, true);
            app.TestSimulatedLightCurtainButton.Text = 'Test Simulated Light Curtain';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end